classdef ASAP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ASAPUIFigure                    matlab.ui.Figure
        ProjectMenu                     matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportRawDataMenu               matlab.ui.container.Menu
        ExportImagesMenu                matlab.ui.container.Menu
        ExportPlotsMenu                 matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        DisplayImagesMenu               matlab.ui.container.Menu
        DisplayPlotsMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProjectTab                      matlab.ui.container.Tab
        ProjectfolderPanel              matlab.ui.container.Panel
        SelectFolder0_1                 matlab.ui.control.Button
        AutomationscriptPanel           matlab.ui.container.Panel
        SelecAutomationScript0_1        matlab.ui.control.Button
        Run0_1                          matlab.ui.control.Button
        SimulateTab                     matlab.ui.container.Tab
        ImagefilesPanel                 matlab.ui.container.Panel
        ListBox1_1                      matlab.ui.control.ListBox
        SimulateandexportPanel          matlab.ui.container.Panel
        SimulateandexportButton1_1      matlab.ui.control.Button
        SimulateTypeTab                 matlab.ui.container.TabGroup
        RandomTab                       matlab.ui.container.Tab
        SimulationparametersPanel       matlab.ui.container.Panel
        FluorophoreEditField1_1         matlab.ui.control.NumericEditField
        fluorophoresLabel1_1            matlab.ui.control.Label
        LabelLengthEditField1_1         matlab.ui.control.NumericEditField
        labellengthLabel1_1             matlab.ui.control.Label
        PhotonCountEditField1_1         matlab.ui.control.NumericEditField
        photoncountLabel1_3             matlab.ui.control.Label
        BlinkingCycleEditField1_1       matlab.ui.control.NumericEditField
        blinkingcyclesLabel1_1          matlab.ui.control.Label
        LateralPrecisionEditField1_1    matlab.ui.control.NumericEditField
        lateralprecisionLabel1_1        matlab.ui.control.Label
        ImagepropertiesPanel_2          matlab.ui.container.Panel
        PixelSizeField1_1               matlab.ui.control.NumericEditField
        pixelsizenmpxLabel1_1           matlab.ui.control.Label
        SamplepreprocessingPanel        matlab.ui.container.Panel
        SegmentCheckBox1_1              matlab.ui.control.CheckBox
        segmentcellLabel_2              matlab.ui.control.Label
        LevelEditField1_1               matlab.ui.control.NumericEditField
        levelLabel1_1                   matlab.ui.control.Label
        StructuredTab                   matlab.ui.container.Tab
        SimulationparametersPanel_2     matlab.ui.container.Panel
        EpitopesEditField1_1            matlab.ui.control.NumericEditField
        LabelLengthEditField1_2         matlab.ui.control.NumericEditField
        epitopesLabel1_1                matlab.ui.control.Label
        labellengthLabel1_2             matlab.ui.control.Label
        StructuresEditField1_1          matlab.ui.control.NumericEditField
        PhotonCountEditField1_2         matlab.ui.control.NumericEditField
        structuresLabel                 matlab.ui.control.Label
        photoncountLabel1_2             matlab.ui.control.Label
        BlinkingCycleEditField1_2       matlab.ui.control.NumericEditField
        blinkingcyclesLabel1_2          matlab.ui.control.Label
        RotationEnabledCheckBox1_1      matlab.ui.control.CheckBox
        rotationenabledLabel1_1         matlab.ui.control.Label
        StructureSizeEditField1_1       matlab.ui.control.NumericEditField
        structuresizeLabel1_1           matlab.ui.control.Label
        LateralPrecisionEditField1_2    matlab.ui.control.NumericEditField
        lateralprecisionLabel1_2        matlab.ui.control.Label
        LabelingEfficiencyEditField1_1  matlab.ui.control.NumericEditField
        labelingefficiencyLabel1_1      matlab.ui.control.Label
        ImagepropertiesPanel            matlab.ui.container.Panel
        PixelSizeEditField1_2           matlab.ui.control.NumericEditField
        pixelsizenmpxLabel1_2           matlab.ui.control.Label
        SamplepreprocessingPanel_3      matlab.ui.container.Panel
        segmentCellCheckBox1_2          matlab.ui.control.CheckBox
        segmentcellLabel                matlab.ui.control.Label
        LevelEditField1_2               matlab.ui.control.NumericEditField
        levelLabel1_2                   matlab.ui.control.Label
        IdentifyTab                     matlab.ui.container.Tab
        ImagefilesPanel_2               matlab.ui.container.Panel
        ListBox2_1                      matlab.ui.control.ListBox
        IdentifyPanel                   matlab.ui.container.Panel
        IdentifyButton2_1               matlab.ui.control.Button
        ExportPanel_4                   matlab.ui.container.Panel
        ExportButton2_1                 matlab.ui.control.Button
        IdentifystructuresusingPanel    matlab.ui.container.Panel
        DropDown2_1                     matlab.ui.control.DropDown
        IdentificationrangePanel        matlab.ui.container.Panel
        SizeUppEditField2_1             matlab.ui.control.NumericEditField
        sizeLabel                       matlab.ui.control.Label
        SizeLowEditField2_1             matlab.ui.control.NumericEditField
        ThresholdingmethodPanel         matlab.ui.container.Panel
        DropDown2_2                     matlab.ui.control.DropDown
        MultiplierEditFieldSize2_1      matlab.ui.control.NumericEditField
        thresholdmultiplierLabel        matlab.ui.control.Label
        CleanerPanel                    matlab.ui.container.Panel
        ParticleSizeEditField2_1        matlab.ui.control.NumericEditField
        particlesizeLabel               matlab.ui.control.Label
        searchradiusLabel               matlab.ui.control.Label
        SearchRadiusEditField2_1        matlab.ui.control.NumericEditField
        ClearBorderCheckBox2_1          matlab.ui.control.CheckBox
        SamplepreprocessingPanel_2      matlab.ui.container.Panel
        SegmentCheckBox2_1              matlab.ui.control.CheckBox
        SegmentcellsampleLabel          matlab.ui.control.Label
        LevelEditField2_1               matlab.ui.control.NumericEditField
        levelLabel1_3                   matlab.ui.control.Label
        SizeparametersPanel             matlab.ui.container.Panel
        MaxStructureSizeSpinner2_1      matlab.ui.control.Spinner
        maxstructuresizeLabel           matlab.ui.control.Label
        AnalyzeTab                      matlab.ui.container.Tab
        asidfilesPanel                  matlab.ui.container.Panel
        ListBox3_1                      matlab.ui.control.ListBox
        SelectplatformButtonGroup       matlab.ui.container.ButtonGroup
        DropDown3_1                     matlab.ui.control.DropDown
        SetpixelparametersButtonGroup   matlab.ui.container.ButtonGroup
        PixelsizenmEditFieldLabel       matlab.ui.control.Label
        PixelSizeEditField3_1           matlab.ui.control.NumericEditField
        ExportPanel_6                   matlab.ui.container.Panel
        ExportButton3_1                 matlab.ui.control.Button
        ExtractPanel_2                  matlab.ui.container.Panel
        ExtractButton3_1                matlab.ui.control.Button
        AnalyzePanel                    matlab.ui.container.Panel
        AnalyzeButton3_1                matlab.ui.control.Button
        SelectanalysismethodButtonGroup  matlab.ui.container.ButtonGroup
        ListBox3_2                      matlab.ui.control.ListBox
        MaxringsizepxLabel              matlab.ui.control.Label
        MaxRingSizeEditField3_1         matlab.ui.control.NumericEditField
        SelectoperationsButtonGroup     matlab.ui.container.ButtonGroup
        ListBox3_3                      matlab.ui.control.ListBox
        ReviewPanel_3                   matlab.ui.container.Panel
        UIAxes3_1                       matlab.ui.control.UIAxes
        DropDown3_3                     matlab.ui.control.DropDown
        Spinner3_1                      matlab.ui.control.Spinner
        UIAxes3_2                       matlab.ui.control.UIAxes
        TextArea3_1                     matlab.ui.control.TextArea
        BinButton3_1                    matlab.ui.control.StateButton
        TrainTab                        matlab.ui.container.Tab
        asanfilesPanel                  matlab.ui.container.Panel
        ListBox4_1                      matlab.ui.control.ListBox
        SelectdescriptorsPanel          matlab.ui.container.Panel
        ListBox4_3                      matlab.ui.control.ListBox
        WriteshapenamesPanel            matlab.ui.container.Panel
        TextArea4_1                     matlab.ui.control.TextArea
        ExtractPanel                    matlab.ui.container.Panel
        ExtractButton4_1                matlab.ui.control.Button
        ManualclassificationPanel       matlab.ui.container.Panel
        UIAxes4_1                       matlab.ui.control.UIAxes
        DropDown4_1                     matlab.ui.control.DropDown
        Spinner4_1                      matlab.ui.control.Spinner
        ListBox4_2                      matlab.ui.control.ListBox
        UIAxes4_2                       matlab.ui.control.UIAxes
        ExportPanel                     matlab.ui.container.Panel
        ExportButton4_1                 matlab.ui.control.Button
        TrainPanel                      matlab.ui.container.Panel
        TrainButton4_1                  matlab.ui.control.Button
        SelectlearnerPanel              matlab.ui.container.Panel
        DropDown4_2                     matlab.ui.control.DropDown
        ClassifyTab                     matlab.ui.container.Tab
        asanfilesPanel_2                matlab.ui.container.Panel
        ListBox5_1                      matlab.ui.control.ListBox
        ClassifyPanel                   matlab.ui.container.Panel
        ClassifyButton5_1               matlab.ui.control.Button
        ExportPanel_5                   matlab.ui.container.Panel
        ExportButton5_1                 matlab.ui.control.Button
        ReviewPanel_2                   matlab.ui.container.Panel
        UIAxes5_1                       matlab.ui.control.UIAxes
        DropDown5_5                     matlab.ui.control.DropDown
        Spinner5_2                      matlab.ui.control.Spinner
        ListBox5_3                      matlab.ui.control.ListBox
        UIAxes5_2                       matlab.ui.control.UIAxes
        BinButton5_1                    matlab.ui.control.StateButton
        ReassignPanel                   matlab.ui.container.Panel
        ShapePanel                      matlab.ui.container.Panel
        ListBox5_2                      matlab.ui.control.ListBox
        CheckBox5_1                     matlab.ui.control.CheckBox
        DropDown5_1                     matlab.ui.control.DropDown
        FeaturePanel                    matlab.ui.container.Panel
        DropDown5_2                     matlab.ui.control.DropDown
        BoundsPanel                     matlab.ui.container.Panel
        EditField5_1                    matlab.ui.control.NumericEditField
        EditField5_2                    matlab.ui.control.NumericEditField
        DropDown5_4                     matlab.ui.control.DropDown
        CheckBox5_2                     matlab.ui.control.CheckBox
        DropDown5_3                     matlab.ui.control.DropDown
        AssignmentSpinnerLabel          matlab.ui.control.Label
        Spinner5_1                      matlab.ui.control.Spinner
        ExtractPanel_7                  matlab.ui.container.Panel
        ExtractButton5_1                matlab.ui.control.Button
        ClusterTab                      matlab.ui.container.Tab
        asclfilesPanel                  matlab.ui.container.Panel
        ListBox6_1                      matlab.ui.control.ListBox
        SelectshapestoclusterPanel      matlab.ui.container.Panel
        ListBox6_2                      matlab.ui.control.ListBox
        ClusterPanel                    matlab.ui.container.Panel
        ClusterButton6_1                matlab.ui.control.Button
        SelectshapedescriptorsPanel_3   matlab.ui.container.Panel
        ListBox6_3                      matlab.ui.control.ListBox
        SelectclusteringmodeButtonGroup  matlab.ui.container.ButtonGroup
        DropDown6_1                     matlab.ui.control.DropDown
        CheckBox6_1                     matlab.ui.control.CheckBox
        ExtractPanel_3                  matlab.ui.container.Panel
        ExtractButton6_1                matlab.ui.control.Button
        ExportPanel_7                   matlab.ui.container.Panel
        ExportButton6_1                 matlab.ui.control.Button
        clustersknownPanel              matlab.ui.container.Panel
        Switch6_1                       matlab.ui.control.Switch
        EditField6_1                    matlab.ui.control.NumericEditField
        PlotTab                         matlab.ui.container.Tab
        asclfilesPanel_2                matlab.ui.container.Panel
        ListBox7_1                      matlab.ui.control.ListBox
        PlotPanel                       matlab.ui.container.Panel
        PlotButton7_1                   matlab.ui.control.Button
        ExportPanel_2                   matlab.ui.container.Panel
        ExportButton7_1                 matlab.ui.control.Button
        ChoosegraphpropertiesPanel      matlab.ui.container.Panel
        AddButton7_1                    matlab.ui.control.Button
        ClearButton7_1                  matlab.ui.control.Button
        GraphpropertiesPanel            matlab.ui.container.Panel
        DropDown7_2                     matlab.ui.control.DropDown
        DropDown7_4                     matlab.ui.control.DropDown
        DropDown7_7                     matlab.ui.control.DropDown
        CheckBox7_1                     matlab.ui.control.CheckBox
        CheckBox7_4                     matlab.ui.control.CheckBox
        CheckBox7_8                     matlab.ui.control.CheckBox
        DropDown7_1                     matlab.ui.control.DropDown
        CheckBox7_7                     matlab.ui.control.CheckBox
        CheckBox7_5                     matlab.ui.control.CheckBox
        CheckBox7_6                     matlab.ui.control.CheckBox
        DropDown7_3                     matlab.ui.control.DropDown
        DropDown7_5                     matlab.ui.control.DropDown
        xylineLabel                     matlab.ui.control.Label
        EditField7_6                    matlab.ui.control.EditField
        EditField7_7                    matlab.ui.control.EditField
        DropDown7_6                     matlab.ui.control.DropDown
        EditField7_2                    matlab.ui.control.EditField
        LinkShapeLabel                  matlab.ui.control.Label
        RowColLabel                     matlab.ui.control.Label
        DescriptorsLabel                matlab.ui.control.Label
        MapFillLabel                    matlab.ui.control.Label
        TypeFitLabel                    matlab.ui.control.Label
        EditField7_1                    matlab.ui.control.EditField
        LabelBins                       matlab.ui.control.Label
        CheckBox7_3                     matlab.ui.control.CheckBox
        DropDown7_8                     matlab.ui.control.DropDown
        CheckBox7_2                     matlab.ui.control.CheckBox
        EditField7_5                    matlab.ui.control.EditField
        FitequationLabel                matlab.ui.control.Label
        ListBox7_3                      matlab.ui.control.ListBox
        FilesLabel                      matlab.ui.control.Label
        EditField7_4                    matlab.ui.control.EditField
        EditField7_3                    matlab.ui.control.EditField
        ListBox7_2                      matlab.ui.control.ListBox
        ExtractPanel_4                  matlab.ui.container.Panel
        ExtractButton7_1                matlab.ui.control.Button
        PlotpropertiesPanel             matlab.ui.container.Panel
        WEditField7_1                   matlab.ui.control.EditField
        FontLabel                       matlab.ui.control.Label
        FEditField7_1                   matlab.ui.control.EditField
        LWLabel                         matlab.ui.control.Label
        LEditField7_1                   matlab.ui.control.EditField
        MontageTab                      matlab.ui.container.Tab
        asclfilesPanel_3                matlab.ui.container.Panel
        ListBox8_1                      matlab.ui.control.ListBox
        MontagePanel                    matlab.ui.container.Panel
        MontageButton8_1                matlab.ui.control.Button
        ExportPanel_3                   matlab.ui.container.Panel
        ExportButton8_1                 matlab.ui.control.Button
        ChoosegalleryparametersPanel    matlab.ui.container.Panel
        ListBox8_2                      matlab.ui.control.ListBox
        AddButton8_1                    matlab.ui.control.Button
        EraseButton8_1                  matlab.ui.control.Button
        GraphpropertiesPanel_2          matlab.ui.container.Panel
        DropDown8_2                     matlab.ui.control.DropDown
        CheckBox8_1                     matlab.ui.control.CheckBox
        DropDown8_1                     matlab.ui.control.DropDown
        FileShapeLabel_2                matlab.ui.control.Label
        RowColLabel_2                   matlab.ui.control.Label
        EditField8_1                    matlab.ui.control.EditField
        LabelScale                      matlab.ui.control.Label
        Spinner8_1                      matlab.ui.control.Spinner
        UIAxes8_1                       matlab.ui.control.UIAxes
        EditField8_2                    matlab.ui.control.NumericEditField
        ParticleLabel                   matlab.ui.control.Label
        CheckBox8_2                     matlab.ui.control.CheckBox
        EditField8_4                    matlab.ui.control.EditField
        EditField8_3                    matlab.ui.control.EditField
        ExtractPanel_6                  matlab.ui.container.Panel
        ExtractButton8_1                matlab.ui.control.Button
        GallerypropertiesPanel          matlab.ui.container.Panel
        TextLabel_2                     matlab.ui.control.Label
        FEditField8_1                   matlab.ui.control.EditField
        ImagesizeLabel                  matlab.ui.control.Label
        IEditField8_1                   matlab.ui.control.EditField
        MsgBox                          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        
        %% app parameters
        pr_options = struct;
        pr_simulationParam = struct;
        pr_identificationParam = struct;
        pr_analysisParam = struct;
        pr_classificationParam = struct;
        pr_clusteringParam = struct;
        pr_plottingParam = struct;
        pr_montagingParam = struct;
        pr_structuresData = struct;
        pr_trainingData = struct;
        pr_assignmentData = struct;
        pr_plottingData = struct;
        pr_montagingData = struct;
        pr_fileList;
        pr_list;
        pr_inputPath;
        pr_exportPath;
        pr_entryPoint = 'UI';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            warning('off','all');
            app.MsgBox.Value = sprintf('%s','ASAP loaded.');
            [currentFolder,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(currentFolder));
        end

        % Callback function
        function SelectdirectoryButton0_1_1Pushed(app, event)
            global exportDir
            exportDir = uigetdir;
            exportDirLastChar = exportDir(end - 20 : end);
            app.FolderPathLabel0_1.Text = exportDirLastChar;
        end

        % Button pushed function: SimulateandexportButton1_1
        function SimulateandexportButton1_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            simulateFn(app);
        end

        % Button pushed function: IdentifyButton2_1
        function IdentifyButton2_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            identifyFn(app);
        end

        % Button pushed function: ExportButton2_1
        function ExportButton2_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            exportFn(app,'Identify');
        end

        % Value changed function: DropDown2_1
        function DropDown2_1ValueChanged(app, event)
            switch app.DropDown2_1.Value
                case 'Size'
                    app.MaxStructureSizeSpinner2_1.Enable = true;
                    app.MaxStructureSizeSpinner2_1.Value = 0;
                otherwise
                    app.MaxStructureSizeSpinner2_1.Enable = false;
                    app.MaxStructureSizeSpinner2_1.Value = 0;
            end
        end

        % Button pushed function: ExtractButton4_1
        function ExtractButton4_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            
            %% defining handles
            listBoxHandle = {app.ListBox4_1,app.ListBox4_2,app.ListBox4_3};
            dropDownHandle = app.DropDown4_1;
            spinnerHandle = app.Spinner4_1;
            textHandle = app.TextArea4_1;
            axesHandle = {app.UIAxes4_1,app.UIAxes4_2};
            
            %% extracting data
            extractFn(app,listBoxHandle{1});
            
            %% reading shape names
            shapeNames = strsplit(textHandle.Value{1},',')';
            
            %% initializing classification buttons with shape names
            listBoxHandle{2}.Items = shapeNames;
            
            %% populating dropdown menu
            dropDownHandle.Items = listBoxHandle{1}.Value;
            dropDownHandle.Value = dropDownHandle.Items{1};
            
            %% intializing spinner
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            
            %% reading structureId
            structureId = spinnerHandle.Value;
           
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% populating features listBox
            listBoxHandle{3}.Items = app.pr_structuresData.file(fileId).descriptors;
            
            %% assigning shape to property
            app.pr_structuresData.file(fileId).shapes{structureId} = listBoxHandle{2}.Value;
        end

        % Button pushed function: TrainButton4_1
        function TrainButton4_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            trainFn(app);
        end

        % Button pushed function: ExportButton4_1
        function ExportButton4_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            exportFn(app,'Train');
        end

        % Button pushed function: ClassifyButton5_1
        function ClassifyButton5_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            
            classifyFn(app);
            
            %% defining handles
            listBoxHandle = {app.ListBox5_1,app.ListBox5_3};
            dropDownHandle = app.DropDown5_5;
            spinnerHandle = app.Spinner5_2;
            axesHandle = {app.UIAxes5_1,app.UIAxes5_2};
            buttonHandle = app.BinButton5_1;
            
            %% initializing classification buttons with shape names
            listBoxHandle{2}.Items = app.pr_trainingData.shapes;
            
            %% populating dropdown menu
            dropDownHandle.Items = listBoxHandle{1}.Value;
            dropDownHandle.Value = dropDownHandle.Items{1};
            
            %% intializing spinner
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            
            %% reading structureId
            structureId = spinnerHandle.Value;
           
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Button pushed function: ExportButton5_1
        function ExportButton5_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            exportFn(app,'Classify');
        end

        % Button pushed function: ExtractButton3_1
        function ExtractButton3_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            extractFn(app,app.ListBox3_1);
        end

        % Button pushed function: AnalyzeButton3_1
        function AnalyzeButton3_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            
            %% calling analyzeFn
            analyzeFn(app);
            
            %% defining handles
            listBoxHandle = app.ListBox3_1;
            dropDownHandle = app.DropDown3_3;
            spinnerHandle = app.Spinner3_1;
            buttonHandle = app.BinButton3_1;
            textHandle = app.TextArea3_1;
            axesHandle = {app.UIAxes3_1,app.UIAxes3_2};
            
            %% populating dropdown menu
            dropDownHandle.Items = listBoxHandle.Value;
            dropDownHandle.Value = dropDownHandle.Items{1};
            
            %% intializing spinner
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            structureId = spinnerHandle.Value;
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
            
            %% populating text box
            initializeTextAuxFn(app,textHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
        end

        % Button pushed function: ExportButton3_1
        function ExportButton3_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            exportFn(app,'Analyze');
        end

        % Button pushed function: ExtractButton6_1
        function ExtractButton6_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            extractFn(app,app.ListBox6_1);
            
            %% defining handles
            listBoxHandle = {app.ListBox6_2,app.ListBox6_3};
            
            %% populating listBoxes
            listBoxHandle{1}.Items = unique(app.pr_structuresData.file(1).shapes);
            listBoxHandle{2}.Items = app.pr_structuresData.file(1).descriptors;
        end

        % Value changed function: Switch6_1
        function Switch6_1ValueChanged(app, event)
            if strcmp(app.Switch6_1.Value,'N')
                app.EditField6_1.Visible = 0;
            else
                app.EditField6_1.Visible = 1;
            end
        end

        % Button pushed function: ClusterButton6_1
        function ClusterButton6_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            clusterFn(app);
        end

        % Button pushed function: ExportButton6_1
        function ExportButton6_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            exportFn(app,'Cluster');
        end

        % Button pushed function: ExtractButton7_1
        function ExtractButton7_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            extractFn(app,app.ListBox7_1);
            
            %% clearing all data
            initializeGraphPropAuxFn(app,'clear');
        end

        % Button pushed function: AddButton7_1
        function AddButton7_1Pushed(app, event)
            initializeGraphPropAuxFn(app,'add');
        end

        % Button pushed function: ClearButton7_1
        function ClearButton7_1Pushed(app, event)
            initializeGraphPropAuxFn(app,'clear');
        end

        % Value changed function: ListBox7_2
        function ListBox7_2ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'read');
        end

        % Value changed function: CheckBox7_1
        function CheckBox7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_1
        function EditField7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: DropDown7_2
        function DropDown7_2ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: DropDown7_4
        function DropDown7_4ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'update','xChanged');
        end

        % Value changed function: DropDown7_3
        function DropDown7_3ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'update','yChanged');
        end

        % Value changed function: DropDown7_7
        function DropDown7_7ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Button pushed function: PlotButton7_1
        function PlotButton7_1Pushed(app, event)
            plotFn(app);
        end

        % Value changed function: CheckBox7_8
        function CheckBox7_8ValueChanged(app, event)
            if app.CheckBox7_8.Value
                initializeGraphPropAuxFn(app,'update','linkChanged');
            end
        end

        % Value changed function: DropDown7_1
        function DropDown7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: CheckBox7_7
        function CheckBox7_7ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: CheckBox7_5
        function CheckBox7_5ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: CheckBox7_6
        function CheckBox7_6ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: CheckBox7_4
        function CheckBox7_4ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_6
        function EditField7_6ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_7
        function EditField7_7ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: DropDown7_6
        function DropDown7_6ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: DropDown7_5
        function DropDown7_5ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_2
        function EditField7_2ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Button pushed function: ExportButton7_1
        function ExportButton7_1Pushed(app, event)
            exportFn(app,'Plot');
        end

        % Button pushed function: SelectFolder0_1
        function SelectFolder0_1ButtonPushed(app, event)
            [inputPath,exportPath,list] = setFolderPathAuxFn(app);
            if isempty(exportPath)
                app.MsgBox.Value = [app.MsgBox.Value ;...
                    sprintf('%s','ASAP error 1: no path is selected.')];
            else
                app.pr_inputPath = inputPath;
                app.pr_exportPath = exportPath;
                app.pr_list = list;
                app.pr_fileList = retrieveFilesAuxFn(app.pr_inputPath,{'.png','.jpg','.tif'});
                fileListWriterAuxFn(app);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            populateListBoxAuxFn(app);
        end

        % Value changed function: CheckBox7_3
        function CheckBox7_3ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: DropDown7_8
        function DropDown7_8ValueChanged2(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Button pushed function: ExtractButton8_1
        function ExtractButton8_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            extractFn(app,app.ListBox8_1);
            
            %% clearing all data
            initializeImagePropAuxFn(app,'clear');
        end

        % Button pushed function: AddButton8_1
        function AddButton8_1Pushed(app, event)
            initializeImagePropAuxFn(app,'add');
        end

        % Button pushed function: EraseButton8_1
        function EraseButton8_1Pushed(app, event)
            initializeImagePropAuxFn(app,'clear');
        end

        % Value changed function: Spinner8_1
        function Spinner8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'update','spinnerChanged');
        end

        % Value changed function: EditField8_1
        function EditField8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: EditField8_2
        function EditField8_2ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: DropDown8_1
        function DropDown8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'update','dropDownChanged');
        end

        % Value changed function: DropDown8_2
        function DropDown8_2ValueChanged(app, event)
            initializeImagePropAuxFn(app,'update','dropDownChanged');
        end

        % Value changed function: CheckBox8_1
        function CheckBox8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: ListBox8_2
        function ListBox8_2ValueChanged(app, event)
            initializeImagePropAuxFn(app,'update','listBoxChanged');
        end

        % Button pushed function: MontageButton8_1
        function MontageButton8_1Pushed(app, event)
            montageFn(app);
        end

        % Button pushed function: ExportButton8_1
        function ExportButton8_1Pushed(app, event)
            exportFn(app,'Montage');
        end

        % Value changed function: Spinner4_1
        function Spinner4_1ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown4_1;
            spinnerHandle = app.Spinner4_1;
            listBoxHandle = app.ListBox4_2;
            axesHandle = {app.UIAxes4_1,app.UIAxes4_2};
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing list box
            initializeListBoxAuxFn(app,listBoxHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
        end

        % Value changed function: DropDown4_1
        function DropDown4_1ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown4_1;
            spinnerHandle = app.Spinner4_1;
            listBoxHandle = app.ListBox4_2;
            axesHandle = {app.UIAxes4_1,app.UIAxes4_2};
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% initializing spinner
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing list box
            initializeListBoxAuxFn(app,listBoxHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
        end

        % Value changed function: ListBox4_2
        function ListBox4_2ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown4_1;
            spinnerHandle = app.Spinner4_1;
            listBoxHandle = app.ListBox4_2;
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% assigning shape to property
            app.pr_structuresData.file(fileId).shapes{structureId} = listBoxHandle.Value;
        end

        % Value changed function: DropDown5_5
        function DropDown5_5ValueChanged(app, event)
            %% defining handles
            listBoxHandle = app.ListBox5_3;
            dropDownHandle = app.DropDown5_5;
            spinnerHandle = app.Spinner5_2;
            axesHandle = {app.UIAxes5_1,app.UIAxes5_2};
            buttonHandle = app.BinButton5_1;
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% initializing spinner
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing list box
            initializeListBoxAuxFn(app,listBoxHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Value changed function: ListBox5_3
        function ListBox5_3ValueChanged(app, event)
            %% defining handles
            listBoxHandle = app.ListBox5_3;
            dropDownHandle = app.DropDown5_5;
            spinnerHandle = app.Spinner5_2;
            
            %% reading fileID and structure ID
            fileId = strcmp(dropDownHandle.Value,dropDownHandle.Items);
            structureId = spinnerHandle.Value;
            
            %% assinging new value to shape sub property
            app.pr_structuresData.file(fileId).shapes{structureId} = listBoxHandle.Value;
        end

        % Value changed function: Spinner5_2
        function Spinner5_2ValueChanged(app, event)
            %% defining handles
            listBoxHandle = app.ListBox5_3;
            dropDownHandle = app.DropDown5_5;
            spinnerHandle = app.Spinner5_2;
            axesHandle = {app.UIAxes5_1,app.UIAxes5_2};
            buttonHandle = app.BinButton5_1;
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing list box
            initializeListBoxAuxFn(app,listBoxHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Callback function
        function Button4_1Pushed(app, event)
            global exportBatch
            fileId = strcmp(app.DropDown5_5.Value,app.DropDown5_5.Items);
            structureId = app.Spinner5_2.Value;
            exportBatch(fileId).binned = [exportBatch(fileId).binned structureId];
        end

        % Value changed function: DropDown3_3
        function DropDown3_3ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown3_3;
            spinnerHandle = app.Spinner3_1;
            buttonHandle = app.BinButton3_1;
            textHandle = app.TextArea3_1;
            axesHandle = {app.UIAxes3_1,app.UIAxes3_2};
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% initializing spinner
            initializeSpinnerAuxFn(app,spinnerHandle,fileId);
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing text
            initializeTextAuxFn(app,textHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Value changed function: Spinner3_1
        function Spinner3_1ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown3_3;
            spinnerHandle = app.Spinner3_1;
            buttonHandle = app.BinButton3_1;
            textHandle = app.TextArea3_1;
            axesHandle = {app.UIAxes3_1,app.UIAxes3_2};
            
            %% reading fileId
            fileId = find(strcmp(dropDownHandle.Value,dropDownHandle.Items));
            
            %% reading structureId
            structureId = spinnerHandle.Value;
            
            %% initializing text
            initializeTextAuxFn(app,textHandle,fileId,structureId);
            
            %% displaying structure
            displayStructureAuxFn(app,axesHandle,fileId,structureId);
            
            %% toggling button
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Value changed function: BinButton3_1
        function BinButton3_1ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown3_3;
            spinnerHandle = app.Spinner3_1;
            buttonHandle = app.BinButton3_1;
            
            %% reading fileId and structureId
            fileId = strcmp(dropDownHandle.Value,dropDownHandle.Items);
            structureId = spinnerHandle.Value;
            
            %% changing binned balue
            app.pr_structuresData.file(fileId).binned(round(structureId)) = buttonHandle.Value;
            
            %% calling toggleBinButton - private function
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Value changed function: BinButton5_1
        function BinButton5_1ValueChanged(app, event)
            %% defining handles
            dropDownHandle = app.DropDown5_5;
            spinnerHandle = app.Spinner5_2;
            buttonHandle = app.BinButton5_1;
            
            %% reading fileId and structureId
            fileId = strcmp(dropDownHandle.Value,dropDownHandle.Items);
            structureId = spinnerHandle.Value;
            
            %% changing binned balue
            app.pr_structuresData.file(fileId).binned(round(structureId)) = buttonHandle.Value;
            
            %% calling toggleBinButton - private function
            toggleButtonAuxFn(app,buttonHandle,fileId,structureId);
        end

        % Value changed function: SegmentCheckBox1_1
        function SegmentCheckBox1_1ValueChanged(app, event)
            if app.SegmentCheckBox1_1.Value
                app.LevelEditField1_1.Enable = true;
            else
                app.LevelEditField1_1.Enable = false;
            end
        end

        % Value changed function: SegmentCheckBox2_1
        function SegmentCheckBox2_1ValueChanged(app, event)
            if app.SegmentCheckBox2_1.Value
                app.LevelEditField2_1.Enable = true;
            else
                app.LevelEditField2_1.Enable = false;
            end
        end

        % Value changed function: EditField7_5
        function EditField7_5ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Callback function
        function ListBox7_4ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Callback function
        function SelectFolder0_2ButtonPushed(app, event)
            global inputPath
            inputPath = uigetdir;
            [~,inputFolderName] = fileparts(inputPath);
            app.FolderNameLabel0_2.Text = inputFolderName;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            delete(app.ASAPUIFigure);
            PASS;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.ASAPUIFigure);
        end

        % Callback function
        function EnableMenu_2Selected(app, event)
            app.EnableMenu_2.Checked = 1;
            app.DisableMenu_2.Checked = 0;
            parpool;
        end

        % Callback function
        function DisableMenu_2Selected(app, event)
            app.EnableMenu_2.Checked = 0;
            app.DisableMenu_2.Checked = 1;
            pool = gcp;
            delete(pool);
            delete(gcp('nocreate'));
        end

        % Value changed function: ListBox3_2
        function ListBox3_2ValueChanged(app, event)
            if ismember('Radial profiling',app.ListBox3_2.Value)
                app.MaxRingSizeEditField3_1.Value = 1;
                app.MaxRingSizeEditField3_1.Enable = 1;
            else
                app.MaxRingSizeEditField3_1.Value = 1;
                app.MaxRingSizeEditField3_1.Enable = 0;
            end
        end

        % Value changed function: ListBox7_3
        function ListBox7_3ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Button pushed function: ExtractButton5_1
        function ExtractButton5_1Pushed(app, event)
            app.pr_entryPoint = 'UI';
            
            extractFn(app,app.ListBox5_1);
            extractFn(app);
            
            %% initializing assignment property
            app.pr_assignmentData = struct();
            
            %% initializing assignment submodule
            app.Spinner5_1.Value = 1;
            dummyVar = true;
            assignmentUpdaterAuxFn(app,dummyVar);
        end

        % Value changed function: Spinner5_1
        function Spinner5_1ValueChanged(app, event)
            dummyVar = true;
            assignmentUpdaterAuxFn(app,dummyVar);
        end

        % Value changed function: ListBox5_2
        function ListBox5_2ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: CheckBox5_1
        function CheckBox5_1ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: DropDown5_1
        function DropDown5_1ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: DropDown5_2
        function DropDown5_2ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: DropDown5_3
        function DropDown5_3ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: EditField5_1
        function EditField5_1ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: CheckBox5_2
        function CheckBox5_2ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: DropDown5_4
        function DropDown5_4ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Value changed function: EditField5_2
        function EditField5_2ValueChanged(app, event)
            assignmentUpdaterAuxFn(app);
        end

        % Button pushed function: SelecAutomationScript0_1
        function SelecAutomationScript0_1ButtonPushed2(app, event)
            try
                [automationScriptFile,automationScriptPath] = uigetfile('*.txt');
                automationScriptCheckerFn(app,automationScriptFile,automationScriptPath);
            catch
                app.MsgBox.Value = [app.MsgBox.Value ;...
                    sprintf('%s','ASAP error 11: no script is loaded.')];
            end
        end

        % Button pushed function: Run0_1
        function Run0_1ButtonPushed(app, event)
            automationScriptRunnerFn(app);
        end

        % Menu selected function: ExportRawDataMenu
        function ExportRawDataMenuSelected(app, event)
            if app.ExportRawDataMenu.Checked
                app.ExportRawDataMenu.Checked = false;
            else
                app.ExportRawDataMenu.Checked = true;
            end
        end

        % Menu selected function: ExportImagesMenu
        function ExportImagesMenuSelected(app, event)
            if app.ExportImagesMenu.Checked
                app.ExportImagesMenu.Checked = false;
            else
                app.ExportImagesMenu.Checked = true;
            end
        end

        % Menu selected function: ExportPlotsMenu
        function ExportPlotsMenuSelected(app, event)
            if app.ExportPlotsMenu.Checked
                app.ExportPlotsMenu.Checked = false;
            else
                app.ExportPlotsMenu.Checked = true;
            end
        end

        % Menu selected function: DisplayImagesMenu
        function DisplayImagesMenuSelected(app, event)
            if app.DisplayImagesMenu.Checked
                app.DisplayImagesMenu.Checked = false;
            else
                app.DisplayImagesMenu.Checked = true;
            end
        end

        % Menu selected function: DisplayPlotsMenu
        function DisplayPlotsMenuSelected(app, event)
            if app.DisplayPlotsMenu.Checked
                app.DisplayPlotsMenu.Checked = false;
            else
                app.DisplayPlotsMenu.Checked = true;
            end
        end

        % Value changed function: CheckBox7_2
        function CheckBox7_2ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_3
        function EditField7_3ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField7_4
        function EditField7_4ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: LEditField7_1
        function LEditField7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: WEditField7_1
        function WEditField7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: FEditField7_1
        function FEditField7_1ValueChanged(app, event)
            initializeGraphPropAuxFn(app,'write');
        end

        % Value changed function: EditField8_3
        function EditField8_3ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: EditField8_4
        function EditField8_4ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: CheckBox8_2
        function CheckBox8_2ValueChanged(app, event)
            initializeImagePropAuxFn(app,'update','checkBoxChanged');
        end

        % Value changed function: IEditField8_1
        function IEditField8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end

        % Value changed function: FEditField8_1
        function FEditField8_1ValueChanged(app, event)
            initializeImagePropAuxFn(app,'write');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ASAPUIFigure = uifigure('Visible', 'off');
            app.ASAPUIFigure.Name = 'ASAP';
            app.ProjectMenu = uimenu(app.ASAPUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewMenu = uimenu(app.ProjectMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.AboutMenu = uimenu(app.ProjectMenu);
            app.AboutMenu.Text = 'About';
            app.ExitMenu = uimenu(app.ProjectMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.OptionsMenu = uimenu(app.ASAPUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ExportMenu = uimenu(app.OptionsMenu);
            app.ExportMenu.Text = 'Export';
            app.ExportRawDataMenu = uimenu(app.ExportMenu);
            app.ExportRawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportRawDataMenuSelected, true);
            app.ExportRawDataMenu.Checked = 'on';
            app.ExportRawDataMenu.Text = 'Export raw data';
            app.ExportImagesMenu = uimenu(app.ExportMenu);
            app.ExportImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportImagesMenuSelected, true);
            app.ExportImagesMenu.Checked = 'on';
            app.ExportImagesMenu.Text = 'Export images (except for Plot and Gallery)';
            app.ExportPlotsMenu = uimenu(app.ExportMenu);
            app.ExportPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPlotsMenuSelected, true);
            app.ExportPlotsMenu.Checked = 'on';
            app.ExportPlotsMenu.Text = 'Export plots (except for Plot module)';
            app.DisplayMenu = uimenu(app.OptionsMenu);
            app.DisplayMenu.Text = 'Display';
            app.DisplayImagesMenu = uimenu(app.DisplayMenu);
            app.DisplayImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayImagesMenuSelected, true);
            app.DisplayImagesMenu.Checked = 'on';
            app.DisplayImagesMenu.Text = 'Display images';
            app.DisplayPlotsMenu = uimenu(app.DisplayMenu);
            app.DisplayPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayPlotsMenuSelected, true);
            app.DisplayPlotsMenu.Checked = 'on';
            app.DisplayPlotsMenu.Text = 'Display plots';
            app.TabGroup = uitabgroup(app.ASAPUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.ProjectfolderPanel = uipanel(app.ProjectTab);
            app.ProjectfolderPanel.Title = '1. Project folder';
            app.SelectFolder0_1 = uibutton(app.ProjectfolderPanel, 'push');
            app.SelectFolder0_1.ButtonPushedFcn = createCallbackFcn(app, @SelectFolder0_1ButtonPushed, true);
            app.SelectFolder0_1.Text = 'Select folder';
            app.AutomationscriptPanel = uipanel(app.ProjectTab);
            app.AutomationscriptPanel.Title = '2. Automation script*';
            app.SelecAutomationScript0_1 = uibutton(app.AutomationscriptPanel, 'push');
            app.SelecAutomationScript0_1.ButtonPushedFcn = createCallbackFcn(app, @SelecAutomationScript0_1ButtonPushed2, true);
            app.SelecAutomationScript0_1.Text = 'Select automation script';
            app.Run0_1 = uibutton(app.AutomationscriptPanel, 'push');
            app.Run0_1.ButtonPushedFcn = createCallbackFcn(app, @Run0_1ButtonPushed, true);
            app.Run0_1.Text = 'Run';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.ImagefilesPanel = uipanel(app.SimulateTab);
            app.ImagefilesPanel.Title = '1. Image files';
            app.ListBox1_1 = uilistbox(app.ImagefilesPanel);
            app.ListBox1_1.Items = {};
            app.ListBox1_1.Multiselect = 'on';
            app.ListBox1_1.Value = {};
            app.SimulateandexportPanel = uipanel(app.SimulateTab);
            app.SimulateandexportPanel.Title = '4. Simulate and export';
            app.SimulateandexportButton1_1 = uibutton(app.SimulateandexportPanel, 'push');
            app.SimulateandexportButton1_1.ButtonPushedFcn = createCallbackFcn(app, @SimulateandexportButton1_1Pushed, true);
            app.SimulateandexportButton1_1.Text = 'Simulate and export';
            app.SimulateTypeTab = uitabgroup(app.SimulateTab);
            app.RandomTab = uitab(app.SimulateTypeTab);
            app.RandomTab.Title = 'Random';
            app.SimulationparametersPanel = uipanel(app.RandomTab);
            app.SimulationparametersPanel.Title = '1. Simulation parameters';
            app.FluorophoreEditField1_1 = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.FluorophoreEditField1_1.Limits = [1 Inf];
            app.FluorophoreEditField1_1.Value = 50;
            app.fluorophoresLabel1_1 = uilabel(app.SimulationparametersPanel);
            app.fluorophoresLabel1_1.Text = '# fluorophores';
            app.LabelLengthEditField1_1 = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.LabelLengthEditField1_1.Limits = [0 Inf];
            app.LabelLengthEditField1_1.Value = 10;
            app.labellengthLabel1_1 = uilabel(app.SimulationparametersPanel);
            app.labellengthLabel1_1.Text = 'label length (nm)';
            app.PhotonCountEditField1_1 = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.PhotonCountEditField1_1.Limits = [1 Inf];
            app.PhotonCountEditField1_1.Value = 1;
            app.photoncountLabel1_3 = uilabel(app.SimulationparametersPanel);
            app.photoncountLabel1_3.Text = 'photon count';
            app.BlinkingCycleEditField1_1 = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.BlinkingCycleEditField1_1.Limits = [1 Inf];
            app.BlinkingCycleEditField1_1.Value = 1;
            app.blinkingcyclesLabel1_1 = uilabel(app.SimulationparametersPanel);
            app.blinkingcyclesLabel1_1.Text = '# blinking cycles';
            app.LateralPrecisionEditField1_1 = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.LateralPrecisionEditField1_1.Limits = [1 Inf];
            app.LateralPrecisionEditField1_1.Value = 1;
            app.lateralprecisionLabel1_1 = uilabel(app.SimulationparametersPanel);
            app.lateralprecisionLabel1_1.Text = 'precision (nm)';
            app.ImagepropertiesPanel_2 = uipanel(app.RandomTab);
            app.ImagepropertiesPanel_2.Title = '2. Image properties';
            app.PixelSizeField1_1 = uieditfield(app.ImagepropertiesPanel_2, 'numeric');
            app.PixelSizeField1_1.Limits = [1 Inf];
            app.PixelSizeField1_1.Value = 10;
            app.pixelsizenmpxLabel1_1 = uilabel(app.ImagepropertiesPanel_2);
            app.pixelsizenmpxLabel1_1.Text = 'pixel size (nm/px)';
            app.SamplepreprocessingPanel = uipanel(app.RandomTab);
            app.SamplepreprocessingPanel.Title = '3. Sample pre-processing';
            app.SegmentCheckBox1_1 = uicheckbox(app.SamplepreprocessingPanel);
            app.SegmentCheckBox1_1.ValueChangedFcn = createCallbackFcn(app, @SegmentCheckBox1_1ValueChanged, true);
            app.SegmentCheckBox1_1.Text = '';
            app.segmentcellLabel_2 = uilabel(app.SamplepreprocessingPanel);
            app.segmentcellLabel_2.Text = 'segment cell';
            app.LevelEditField1_1 = uieditfield(app.SamplepreprocessingPanel, 'numeric');
            app.LevelEditField1_1.Limits = [0 Inf];
            app.LevelEditField1_1.Enable = 'off';
            app.LevelEditField1_1.Value = 50;
            app.levelLabel1_1 = uilabel(app.SamplepreprocessingPanel);
            app.levelLabel1_1.Text = 'level';
            app.StructuredTab = uitab(app.SimulateTypeTab);
            app.StructuredTab.Title = 'Structured';
            app.SimulationparametersPanel_2 = uipanel(app.StructuredTab);
            app.SimulationparametersPanel_2.Title = '1. Simulation parameters';
            app.EpitopesEditField1_1 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.EpitopesEditField1_1.Limits = [1 Inf];
            app.EpitopesEditField1_1.Value = 1;
            app.LabelLengthEditField1_2 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.LabelLengthEditField1_2.Limits = [0 Inf];
            app.LabelLengthEditField1_2.Value = 10;
            app.epitopesLabel1_1 = uilabel(app.SimulationparametersPanel_2);
            app.epitopesLabel1_1.Text = '# epitopes';
            app.labellengthLabel1_2 = uilabel(app.SimulationparametersPanel_2);
            app.labellengthLabel1_2.Text = 'label length (nm)';
            app.StructuresEditField1_1 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.StructuresEditField1_1.Limits = [1 Inf];
            app.StructuresEditField1_1.Value = 1;
            app.PhotonCountEditField1_2 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.PhotonCountEditField1_2.Limits = [1 Inf];
            app.PhotonCountEditField1_2.Value = 1;
            app.structuresLabel = uilabel(app.SimulationparametersPanel_2);
            app.structuresLabel.Text = '# structures';
            app.photoncountLabel1_2 = uilabel(app.SimulationparametersPanel_2);
            app.photoncountLabel1_2.Text = 'photon count';
            app.BlinkingCycleEditField1_2 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.BlinkingCycleEditField1_2.Limits = [1 Inf];
            app.BlinkingCycleEditField1_2.Value = 1;
            app.blinkingcyclesLabel1_2 = uilabel(app.SimulationparametersPanel_2);
            app.blinkingcyclesLabel1_2.Text = '# blinking cycles';
            app.RotationEnabledCheckBox1_1 = uicheckbox(app.SimulationparametersPanel_2);
            app.RotationEnabledCheckBox1_1.Text = '';
            app.rotationenabledLabel1_1 = uilabel(app.SimulationparametersPanel_2);
            app.rotationenabledLabel1_1.Text = 'rotation enabled';
            app.StructureSizeEditField1_1 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.StructureSizeEditField1_1.Limits = [1 Inf];
            app.StructureSizeEditField1_1.Value = 1;
            app.structuresizeLabel1_1 = uilabel(app.SimulationparametersPanel_2);
            app.structuresizeLabel1_1.Text = 'structure size (nm)';
            app.LateralPrecisionEditField1_2 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.LateralPrecisionEditField1_2.Limits = [1 Inf];
            app.LateralPrecisionEditField1_2.Value = 1;
            app.lateralprecisionLabel1_2 = uilabel(app.SimulationparametersPanel_2);
            app.lateralprecisionLabel1_2.Text = 'precision (nm)';
            app.LabelingEfficiencyEditField1_1 = uieditfield(app.SimulationparametersPanel_2, 'numeric');
            app.LabelingEfficiencyEditField1_1.Limits = [0.1 1];
            app.LabelingEfficiencyEditField1_1.Value = 0.1;
            app.labelingefficiencyLabel1_1 = uilabel(app.SimulationparametersPanel_2);
            app.labelingefficiencyLabel1_1.Text = 'labeling efficiency';
            app.ImagepropertiesPanel = uipanel(app.StructuredTab);
            app.ImagepropertiesPanel.Title = '2. Image properties';
            app.PixelSizeEditField1_2 = uieditfield(app.ImagepropertiesPanel, 'numeric');
            app.PixelSizeEditField1_2.Limits = [1 Inf];
            app.PixelSizeEditField1_2.Value = 10;
            app.pixelsizenmpxLabel1_2 = uilabel(app.ImagepropertiesPanel);
            app.pixelsizenmpxLabel1_2.Text = 'pixel size (nm/px)';
            app.SamplepreprocessingPanel_3 = uipanel(app.StructuredTab);
            app.SamplepreprocessingPanel_3.Title = '3. Sample pre-processing';
            app.segmentCellCheckBox1_2 = uicheckbox(app.SamplepreprocessingPanel_3);
            app.segmentCellCheckBox1_2.Text = '';
            app.segmentcellLabel = uilabel(app.SamplepreprocessingPanel_3);
            app.segmentcellLabel.Text = 'segment cell';
            app.LevelEditField1_2 = uieditfield(app.SamplepreprocessingPanel_3, 'numeric');
            app.LevelEditField1_2.Limits = [0 Inf];
            app.LevelEditField1_2.Value = 1;
            app.levelLabel1_2 = uilabel(app.SamplepreprocessingPanel_3);
            app.levelLabel1_2.Text = 'level';
            app.IdentifyTab = uitab(app.TabGroup);
            app.IdentifyTab.Title = 'Identify';
            app.ImagefilesPanel_2 = uipanel(app.IdentifyTab);
            app.ImagefilesPanel_2.Title = '1. Image files';
            app.ListBox2_1 = uilistbox(app.ImagefilesPanel_2);
            app.ListBox2_1.Items = {};
            app.ListBox2_1.Multiselect = 'on';
            app.ListBox2_1.Value = {};
            app.IdentifyPanel = uipanel(app.IdentifyTab);
            app.IdentifyPanel.Title = '8. Identify';
            app.IdentifyButton2_1 = uibutton(app.IdentifyPanel, 'push');
            app.IdentifyButton2_1.ButtonPushedFcn = createCallbackFcn(app, @IdentifyButton2_1Pushed, true);
            app.IdentifyButton2_1.Text = 'Identify';
            app.ExportPanel_4 = uipanel(app.IdentifyTab);
            app.ExportPanel_4.Title = '9. Export';
            app.ExportButton2_1 = uibutton(app.ExportPanel_4, 'push');
            app.ExportButton2_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton2_1Pushed, true);
            app.ExportButton2_1.Text = 'Export';
            app.IdentifystructuresusingPanel = uipanel(app.IdentifyTab);
            app.IdentifystructuresusingPanel.Title = '3. Identify structures using';
            app.DropDown2_1 = uidropdown(app.IdentifystructuresusingPanel);
            app.DropDown2_1.Items = {'Connectivity', 'Size'};
            app.DropDown2_1.ValueChangedFcn = createCallbackFcn(app, @DropDown2_1ValueChanged, true);
            app.DropDown2_1.Value = 'Connectivity';
            app.IdentificationrangePanel = uipanel(app.IdentifyTab);
            app.IdentificationrangePanel.Title = '7. Identification range';
            app.SizeUppEditField2_1 = uieditfield(app.IdentificationrangePanel, 'numeric');
            app.sizeLabel = uilabel(app.IdentificationrangePanel);
            app.sizeLabel.Text = 'size';
            app.SizeLowEditField2_1 = uieditfield(app.IdentificationrangePanel, 'numeric');
            app.SizeLowEditField2_1.Limits = [0 Inf];
            app.ThresholdingmethodPanel = uipanel(app.IdentifyTab);
            app.ThresholdingmethodPanel.Title = '4. Thresholding method';
            app.DropDown2_2 = uidropdown(app.ThresholdingmethodPanel);
            app.DropDown2_2.Items = {'Fixed', 'Relative'};
            app.DropDown2_2.Value = 'Fixed';
            app.MultiplierEditFieldSize2_1 = uieditfield(app.ThresholdingmethodPanel, 'numeric');
            app.MultiplierEditFieldSize2_1.Limits = [0 Inf];
            app.MultiplierEditFieldSize2_1.Value = 1;
            app.thresholdmultiplierLabel = uilabel(app.ThresholdingmethodPanel);
            app.thresholdmultiplierLabel.Text = 'threshold multiplier';
            app.CleanerPanel = uipanel(app.IdentifyTab);
            app.CleanerPanel.Title = '5. Cleaner';
            app.ParticleSizeEditField2_1 = uieditfield(app.CleanerPanel, 'numeric');
            app.particlesizeLabel = uilabel(app.CleanerPanel);
            app.particlesizeLabel.Text = 'particle size';
            app.searchradiusLabel = uilabel(app.CleanerPanel);
            app.searchradiusLabel.Text = 'search radius';
            app.SearchRadiusEditField2_1 = uieditfield(app.CleanerPanel, 'numeric');
            app.ClearBorderCheckBox2_1 = uicheckbox(app.CleanerPanel);
            app.ClearBorderCheckBox2_1.Text = 'clear image borders';
            app.SamplepreprocessingPanel_2 = uipanel(app.IdentifyTab);
            app.SamplepreprocessingPanel_2.Title = '2. Sample pre-processing';
            app.SegmentCheckBox2_1 = uicheckbox(app.SamplepreprocessingPanel_2);
            app.SegmentCheckBox2_1.ValueChangedFcn = createCallbackFcn(app, @SegmentCheckBox2_1ValueChanged, true);
            app.SegmentCheckBox2_1.Text = '';
            app.SegmentcellsampleLabel = uilabel(app.SamplepreprocessingPanel_2);
            app.SegmentcellsampleLabel.Text = 'Segment cell / sample';
            app.LevelEditField2_1 = uieditfield(app.SamplepreprocessingPanel_2, 'numeric');
            app.LevelEditField2_1.Limits = [0 Inf];
            app.LevelEditField2_1.Enable = 'off';
            app.LevelEditField2_1.Value = 1;
            app.levelLabel1_3 = uilabel(app.SamplepreprocessingPanel_2);
            app.levelLabel1_3.Text = 'level';
            app.SizeparametersPanel = uipanel(app.IdentifyTab);
            app.SizeparametersPanel.Title = '6. Size parameters';
            app.MaxStructureSizeSpinner2_1 = uispinner(app.SizeparametersPanel);
            app.MaxStructureSizeSpinner2_1.Enable = 'off';
            app.maxstructuresizeLabel = uilabel(app.SizeparametersPanel);
            app.maxstructuresizeLabel.Text = 'max structure size';
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.asidfilesPanel = uipanel(app.AnalyzeTab);
            app.asidfilesPanel.Title = '1. asid files';
            app.ListBox3_1 = uilistbox(app.asidfilesPanel);
            app.ListBox3_1.Items = {};
            app.ListBox3_1.Multiselect = 'on';
            app.ListBox3_1.Value = {};
            app.SelectplatformButtonGroup = uibuttongroup(app.AnalyzeTab);
            app.SelectplatformButtonGroup.Title = '4. Select platform';
            app.DropDown3_1 = uidropdown(app.SelectplatformButtonGroup);
            app.DropDown3_1.Items = {'CPU'};
            app.DropDown3_1.Value = 'CPU';
            app.SetpixelparametersButtonGroup = uibuttongroup(app.AnalyzeTab);
            app.SetpixelparametersButtonGroup.Title = '3. Set pixel parameters';
            app.PixelsizenmEditFieldLabel = uilabel(app.SetpixelparametersButtonGroup);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelSizeEditField3_1 = uieditfield(app.SetpixelparametersButtonGroup, 'numeric');
            app.ExportPanel_6 = uipanel(app.AnalyzeTab);
            app.ExportPanel_6.Title = '9. Export';
            app.ExportButton3_1 = uibutton(app.ExportPanel_6, 'push');
            app.ExportButton3_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton3_1Pushed, true);
            app.ExportButton3_1.Text = 'Export';
            app.ExtractPanel_2 = uipanel(app.AnalyzeTab);
            app.ExtractPanel_2.Title = '2. Extract';
            app.ExtractButton3_1 = uibutton(app.ExtractPanel_2, 'push');
            app.ExtractButton3_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton3_1Pushed, true);
            app.ExtractButton3_1.Text = 'Extract';
            app.AnalyzePanel = uipanel(app.AnalyzeTab);
            app.AnalyzePanel.Title = '7. Analyze';
            app.AnalyzeButton3_1 = uibutton(app.AnalyzePanel, 'push');
            app.AnalyzeButton3_1.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton3_1Pushed, true);
            app.AnalyzeButton3_1.Text = 'Analyze';
            app.SelectanalysismethodButtonGroup = uibuttongroup(app.AnalyzeTab);
            app.SelectanalysismethodButtonGroup.Title = '5. Select analysis method';
            app.ListBox3_2 = uilistbox(app.SelectanalysismethodButtonGroup);
            app.ListBox3_2.Items = {'Pixel counting', 'Ellipse fitting', 'Radial profiling'};
            app.ListBox3_2.Multiselect = 'on';
            app.ListBox3_2.ValueChangedFcn = createCallbackFcn(app, @ListBox3_2ValueChanged, true);
            app.ListBox3_2.Value = {'Pixel counting'};
            app.MaxringsizepxLabel = uilabel(app.SelectanalysismethodButtonGroup);
            app.MaxringsizepxLabel.Text = 'Max ring size (px)';
            app.MaxRingSizeEditField3_1 = uieditfield(app.SelectanalysismethodButtonGroup, 'numeric');
            app.MaxRingSizeEditField3_1.Limits = [1 Inf];
            app.MaxRingSizeEditField3_1.Enable = 'off';
            app.MaxRingSizeEditField3_1.Value = 1;
            app.SelectoperationsButtonGroup = uibuttongroup(app.AnalyzeTab);
            app.SelectoperationsButtonGroup.Title = '6. Select operation(s)';
            app.ListBox3_3 = uilistbox(app.SelectoperationsButtonGroup);
            app.ListBox3_3.Items = {'None', 'Fill', 'Bridge', 'Close', 'Open', 'Clear', 'Rotate', 'Center', 'Resize'};
            app.ListBox3_3.Multiselect = 'on';
            app.ListBox3_3.Value = {'None'};
            app.ReviewPanel_3 = uipanel(app.AnalyzeTab);
            app.ReviewPanel_3.Title = '8. Review';
            app.UIAxes3_1 = uiaxes(app.ReviewPanel_3);
            app.UIAxes3_1.XTick = [];
            app.UIAxes3_1.XTickLabel = {'[ ]'};
            app.UIAxes3_1.YTick = [];
            app.DropDown3_3 = uidropdown(app.ReviewPanel_3);
            app.DropDown3_3.ValueChangedFcn = createCallbackFcn(app, @DropDown3_3ValueChanged, true);
            app.Spinner3_1 = uispinner(app.ReviewPanel_3);
            app.Spinner3_1.ValueChangedFcn = createCallbackFcn(app, @Spinner3_1ValueChanged, true);
            app.UIAxes3_2 = uiaxes(app.ReviewPanel_3);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.XTickLabel = {'[ ]'};
            app.UIAxes3_2.YTick = [];
            app.TextArea3_1 = uitextarea(app.ReviewPanel_3);
            app.BinButton3_1 = uibutton(app.ReviewPanel_3, 'state');
            app.BinButton3_1.ValueChangedFcn = createCallbackFcn(app, @BinButton3_1ValueChanged, true);
            app.BinButton3_1.Text = 'Bin';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.asanfilesPanel = uipanel(app.TrainTab);
            app.asanfilesPanel.Title = '1. asan file(s)';
            app.ListBox4_1 = uilistbox(app.asanfilesPanel);
            app.ListBox4_1.Items = {};
            app.ListBox4_1.Multiselect = 'on';
            app.ListBox4_1.Value = {};
            app.SelectdescriptorsPanel = uipanel(app.TrainTab);
            app.SelectdescriptorsPanel.Title = '6. Select descriptor(s)';
            app.ListBox4_3 = uilistbox(app.SelectdescriptorsPanel);
            app.ListBox4_3.Items = {};
            app.ListBox4_3.Multiselect = 'on';
            app.ListBox4_3.Value = {};
            app.WriteshapenamesPanel = uipanel(app.TrainTab);
            app.WriteshapenamesPanel.Title = '2. Write shape names';
            app.TextArea4_1 = uitextarea(app.WriteshapenamesPanel);
            app.TextArea4_1.Value = {'shape1,shape2,shape3'};
            app.ExtractPanel = uipanel(app.TrainTab);
            app.ExtractPanel.Title = '3. Extract';
            app.ExtractButton4_1 = uibutton(app.ExtractPanel, 'push');
            app.ExtractButton4_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton4_1Pushed, true);
            app.ExtractButton4_1.Text = 'Extract';
            app.ManualclassificationPanel = uipanel(app.TrainTab);
            app.ManualclassificationPanel.Title = '4. Manual classification';
            app.UIAxes4_1 = uiaxes(app.ManualclassificationPanel);
            app.UIAxes4_1.XTick = [];
            app.UIAxes4_1.XTickLabel = {'[ ]'};
            app.UIAxes4_1.YTick = [];
            app.DropDown4_1 = uidropdown(app.ManualclassificationPanel);
            app.DropDown4_1.ValueChangedFcn = createCallbackFcn(app, @DropDown4_1ValueChanged, true);
            app.Spinner4_1 = uispinner(app.ManualclassificationPanel);
            app.Spinner4_1.ValueChangedFcn = createCallbackFcn(app, @Spinner4_1ValueChanged, true);
            app.ListBox4_2 = uilistbox(app.ManualclassificationPanel);
            app.ListBox4_2.Items = {};
            app.ListBox4_2.ValueChangedFcn = createCallbackFcn(app, @ListBox4_2ValueChanged, true);
            app.ListBox4_2.Value = {};
            app.UIAxes4_2 = uiaxes(app.ManualclassificationPanel);
            app.UIAxes4_2.XTick = [];
            app.UIAxes4_2.XTickLabel = {'[ ]'};
            app.UIAxes4_2.YTick = [];
            app.ExportPanel = uipanel(app.TrainTab);
            app.ExportPanel.Title = '8. Export';
            app.ExportButton4_1 = uibutton(app.ExportPanel, 'push');
            app.ExportButton4_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton4_1Pushed, true);
            app.ExportButton4_1.Text = 'Export';
            app.TrainPanel = uipanel(app.TrainTab);
            app.TrainPanel.Title = '7. Train';
            app.TrainButton4_1 = uibutton(app.TrainPanel, 'push');
            app.TrainButton4_1.ButtonPushedFcn = createCallbackFcn(app, @TrainButton4_1Pushed, true);
            app.TrainButton4_1.Text = 'Train';
            app.SelectlearnerPanel = uipanel(app.TrainTab);
            app.SelectlearnerPanel.Title = '5. Select learner';
            app.DropDown4_2 = uidropdown(app.SelectlearnerPanel);
            app.DropDown4_2.Items = {'discriminant', 'knn', 'naivebayes', 'svm', 'tree'};
            app.DropDown4_2.Value = 'discriminant';
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'Classify';
            app.asanfilesPanel_2 = uipanel(app.ClassifyTab);
            app.asanfilesPanel_2.Title = '1. asan file(s)';
            app.ListBox5_1 = uilistbox(app.asanfilesPanel_2);
            app.ListBox5_1.Items = {};
            app.ListBox5_1.Multiselect = 'on';
            app.ListBox5_1.Value = {};
            app.ClassifyPanel = uipanel(app.ClassifyTab);
            app.ClassifyPanel.Title = '5. Classify';
            app.ClassifyButton5_1 = uibutton(app.ClassifyPanel, 'push');
            app.ClassifyButton5_1.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButton5_1Pushed, true);
            app.ClassifyButton5_1.Text = 'Classify';
            app.ExportPanel_5 = uipanel(app.ClassifyTab);
            app.ExportPanel_5.Title = '7. Export';
            app.ExportButton5_1 = uibutton(app.ExportPanel_5, 'push');
            app.ExportButton5_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton5_1Pushed, true);
            app.ExportButton5_1.Text = 'Export';
            app.ReviewPanel_2 = uipanel(app.ClassifyTab);
            app.ReviewPanel_2.Title = '6. Review';
            app.UIAxes5_1 = uiaxes(app.ReviewPanel_2);
            app.UIAxes5_1.XTick = [];
            app.UIAxes5_1.XTickLabel = {'[ ]'};
            app.UIAxes5_1.YTick = [];
            app.DropDown5_5 = uidropdown(app.ReviewPanel_2);
            app.DropDown5_5.ValueChangedFcn = createCallbackFcn(app, @DropDown5_5ValueChanged, true);
            app.Spinner5_2 = uispinner(app.ReviewPanel_2);
            app.Spinner5_2.ValueChangedFcn = createCallbackFcn(app, @Spinner5_2ValueChanged, true);
            app.ListBox5_3 = uilistbox(app.ReviewPanel_2);
            app.ListBox5_3.Items = {};
            app.ListBox5_3.ValueChangedFcn = createCallbackFcn(app, @ListBox5_3ValueChanged, true);
            app.ListBox5_3.Value = {};
            app.UIAxes5_2 = uiaxes(app.ReviewPanel_2);
            app.UIAxes5_2.XTick = [];
            app.UIAxes5_2.XTickLabel = {'[ ]'};
            app.UIAxes5_2.YTick = [];
            app.BinButton5_1 = uibutton(app.ReviewPanel_2, 'state');
            app.BinButton5_1.ValueChangedFcn = createCallbackFcn(app, @BinButton5_1ValueChanged, true);
            app.BinButton5_1.Text = 'Bin';
            app.ReassignPanel = uipanel(app.ClassifyTab);
            app.ReassignPanel.Title = '4. Re-assign';
            app.ShapePanel = uipanel(app.ReassignPanel);
            app.ShapePanel.Title = 'Shape';
            app.ListBox5_2 = uilistbox(app.ShapePanel);
            app.ListBox5_2.Items = {};
            app.ListBox5_2.Multiselect = 'on';
            app.ListBox5_2.ValueChangedFcn = createCallbackFcn(app, @ListBox5_2ValueChanged, true);
            app.ListBox5_2.Value = {};
            app.CheckBox5_1 = uicheckbox(app.ShapePanel);
            app.CheckBox5_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox5_1ValueChanged, true);
            app.CheckBox5_1.Text = 'Re-assign to';
            app.DropDown5_1 = uidropdown(app.ShapePanel);
            app.DropDown5_1.ValueChangedFcn = createCallbackFcn(app, @DropDown5_1ValueChanged, true);
            app.FeaturePanel = uipanel(app.ReassignPanel);
            app.FeaturePanel.Title = 'Feature';
            app.DropDown5_2 = uidropdown(app.FeaturePanel);
            app.DropDown5_2.ValueChangedFcn = createCallbackFcn(app, @DropDown5_2ValueChanged, true);
            app.BoundsPanel = uipanel(app.ReassignPanel);
            app.BoundsPanel.Title = 'Bounds';
            app.EditField5_1 = uieditfield(app.BoundsPanel, 'numeric');
            app.EditField5_1.ValueChangedFcn = createCallbackFcn(app, @EditField5_1ValueChanged, true);
            app.EditField5_2 = uieditfield(app.BoundsPanel, 'numeric');
            app.EditField5_2.ValueChangedFcn = createCallbackFcn(app, @EditField5_2ValueChanged, true);
            app.DropDown5_4 = uidropdown(app.BoundsPanel);
            app.DropDown5_4.Items = {'>', '<', '='};
            app.DropDown5_4.ValueChangedFcn = createCallbackFcn(app, @DropDown5_4ValueChanged, true);
            app.DropDown5_4.Value = '>';
            app.CheckBox5_2 = uicheckbox(app.BoundsPanel);
            app.CheckBox5_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox5_2ValueChanged, true);
            app.CheckBox5_2.Text = '';
            app.DropDown5_3 = uidropdown(app.BoundsPanel);
            app.DropDown5_3.Items = {'>', '<', '='};
            app.DropDown5_3.ValueChangedFcn = createCallbackFcn(app, @DropDown5_3ValueChanged, true);
            app.DropDown5_3.Value = '>';
            app.AssignmentSpinnerLabel = uilabel(app.ReassignPanel);
            app.AssignmentSpinnerLabel.Text = 'Assignment #';
            app.Spinner5_1 = uispinner(app.ReassignPanel);
            app.Spinner5_1.Limits = [1 Inf];
            app.Spinner5_1.ValueChangedFcn = createCallbackFcn(app, @Spinner5_1ValueChanged, true);
            app.Spinner5_1.Value = 1;
            app.ExtractPanel_7 = uipanel(app.ClassifyTab);
            app.ExtractPanel_7.Title = '3. Extract';
            app.ExtractButton5_1 = uibutton(app.ExtractPanel_7, 'push');
            app.ExtractButton5_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton5_1Pushed, true);
            app.ExtractButton5_1.Text = 'Extract';
            app.ClusterTab = uitab(app.TabGroup);
            app.ClusterTab.Title = 'Cluster';
            app.asclfilesPanel = uipanel(app.ClusterTab);
            app.asclfilesPanel.Title = '1. ascl file(s)';
            app.ListBox6_1 = uilistbox(app.asclfilesPanel);
            app.ListBox6_1.Items = {};
            app.ListBox6_1.Multiselect = 'on';
            app.ListBox6_1.Value = {};
            app.SelectshapestoclusterPanel = uipanel(app.ClusterTab);
            app.SelectshapestoclusterPanel.Title = '3. Select shape(s) to cluster';
            app.ListBox6_2 = uilistbox(app.SelectshapestoclusterPanel);
            app.ListBox6_2.Items = {};
            app.ListBox6_2.Multiselect = 'on';
            app.ListBox6_2.Value = {};
            app.ClusterPanel = uipanel(app.ClusterTab);
            app.ClusterPanel.Title = '7. Cluster';
            app.ClusterButton6_1 = uibutton(app.ClusterPanel, 'push');
            app.ClusterButton6_1.ButtonPushedFcn = createCallbackFcn(app, @ClusterButton6_1Pushed, true);
            app.ClusterButton6_1.Text = 'Cluster';
            app.SelectshapedescriptorsPanel_3 = uipanel(app.ClusterTab);
            app.SelectshapedescriptorsPanel_3.Title = '4. Select shape descriptors';
            app.ListBox6_3 = uilistbox(app.SelectshapedescriptorsPanel_3);
            app.ListBox6_3.Items = {};
            app.ListBox6_3.Multiselect = 'on';
            app.ListBox6_3.Value = {};
            app.SelectclusteringmodeButtonGroup = uibuttongroup(app.ClusterTab);
            app.SelectclusteringmodeButtonGroup.Title = '5. Select clustering mode';
            app.DropDown6_1 = uidropdown(app.SelectclusteringmodeButtonGroup);
            app.DropDown6_1.Items = {'Centroid', 'Gaussian mixture'};
            app.DropDown6_1.Value = 'Centroid';
            app.CheckBox6_1 = uicheckbox(app.SelectclusteringmodeButtonGroup);
            app.CheckBox6_1.Text = 'cluster all';
            app.ExtractPanel_3 = uipanel(app.ClusterTab);
            app.ExtractPanel_3.Title = '2. Extract';
            app.ExtractButton6_1 = uibutton(app.ExtractPanel_3, 'push');
            app.ExtractButton6_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton6_1Pushed, true);
            app.ExtractButton6_1.Text = 'Extract';
            app.ExportPanel_7 = uipanel(app.ClusterTab);
            app.ExportPanel_7.Title = '8. Export';
            app.ExportButton6_1 = uibutton(app.ExportPanel_7, 'push');
            app.ExportButton6_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton6_1Pushed, true);
            app.ExportButton6_1.Text = 'Export';
            app.clustersknownPanel = uipanel(app.ClusterTab);
            app.clustersknownPanel.Title = '6. # clusters known';
            app.Switch6_1 = uiswitch(app.clustersknownPanel, 'slider');
            app.Switch6_1.Items = {'N', 'Y'};
            app.Switch6_1.ValueChangedFcn = createCallbackFcn(app, @Switch6_1ValueChanged, true);
            app.Switch6_1.Value = 'N';
            app.EditField6_1 = uieditfield(app.clustersknownPanel, 'numeric');
            app.EditField6_1.Limits = [1 Inf];
            app.EditField6_1.Visible = 'off';
            app.EditField6_1.Value = 1;
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.asclfilesPanel_2 = uipanel(app.PlotTab);
            app.asclfilesPanel_2.Title = '1. ascl file(s)';
            app.ListBox7_1 = uilistbox(app.asclfilesPanel_2);
            app.ListBox7_1.Items = {};
            app.ListBox7_1.Multiselect = 'on';
            app.ListBox7_1.Value = {};
            app.PlotPanel = uipanel(app.PlotTab);
            app.PlotPanel.Title = '5. Plot';
            app.PlotButton7_1 = uibutton(app.PlotPanel, 'push');
            app.PlotButton7_1.ButtonPushedFcn = createCallbackFcn(app, @PlotButton7_1Pushed, true);
            app.PlotButton7_1.Text = 'Plot';
            app.ExportPanel_2 = uipanel(app.PlotTab);
            app.ExportPanel_2.Title = '6. Export';
            app.ExportButton7_1 = uibutton(app.ExportPanel_2, 'push');
            app.ExportButton7_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton7_1Pushed, true);
            app.ExportButton7_1.Text = 'Export';
            app.ChoosegraphpropertiesPanel = uipanel(app.PlotTab);
            app.ChoosegraphpropertiesPanel.Title = '4. Choose graph properties';
            app.AddButton7_1 = uibutton(app.ChoosegraphpropertiesPanel, 'push');
            app.AddButton7_1.ButtonPushedFcn = createCallbackFcn(app, @AddButton7_1Pushed, true);
            app.AddButton7_1.Text = '+';
            app.ClearButton7_1 = uibutton(app.ChoosegraphpropertiesPanel, 'push');
            app.ClearButton7_1.ButtonPushedFcn = createCallbackFcn(app, @ClearButton7_1Pushed, true);
            app.ClearButton7_1.Text = 'clear';
            app.GraphpropertiesPanel = uipanel(app.ChoosegraphpropertiesPanel);
            app.GraphpropertiesPanel.Title = 'Graph properties';
            app.DropDown7_2 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_2.Items = {};
            app.DropDown7_2.ValueChangedFcn = createCallbackFcn(app, @DropDown7_2ValueChanged, true);
            app.DropDown7_2.Value = {};
            app.DropDown7_4 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_4.Items = {};
            app.DropDown7_4.ValueChangedFcn = createCallbackFcn(app, @DropDown7_4ValueChanged, true);
            app.DropDown7_4.Value = {};
            app.DropDown7_7 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_7.Items = {};
            app.DropDown7_7.ValueChangedFcn = createCallbackFcn(app, @DropDown7_7ValueChanged, true);
            app.DropDown7_7.Value = {};
            app.CheckBox7_1 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_1ValueChanged, true);
            app.CheckBox7_1.Text = 'include';
            app.CheckBox7_4 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_4ValueChanged, true);
            app.CheckBox7_4.Text = 'legend';
            app.CheckBox7_8 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_8ValueChanged, true);
            app.CheckBox7_8.Text = 'link';
            app.DropDown7_1 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_1.Items = {};
            app.DropDown7_1.ValueChangedFcn = createCallbackFcn(app, @DropDown7_1ValueChanged, true);
            app.DropDown7_1.Value = {};
            app.CheckBox7_7 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_7ValueChanged, true);
            app.CheckBox7_7.Text = 'split';
            app.CheckBox7_5 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_5ValueChanged, true);
            app.CheckBox7_5.Text = 'flip';
            app.CheckBox7_6 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_6ValueChanged, true);
            app.CheckBox7_6.Text = 'box';
            app.DropDown7_3 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_3.Items = {};
            app.DropDown7_3.ValueChangedFcn = createCallbackFcn(app, @DropDown7_3ValueChanged, true);
            app.DropDown7_3.Value = {};
            app.DropDown7_5 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_5.Items = {};
            app.DropDown7_5.ValueChangedFcn = createCallbackFcn(app, @DropDown7_5ValueChanged, true);
            app.DropDown7_5.Value = {};
            app.xylineLabel = uilabel(app.GraphpropertiesPanel);
            app.xylineLabel.Text = 'x / y line';
            app.EditField7_6 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_6.ValueChangedFcn = createCallbackFcn(app, @EditField7_6ValueChanged, true);
            app.EditField7_7 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_7.ValueChangedFcn = createCallbackFcn(app, @EditField7_7ValueChanged, true);
            app.DropDown7_6 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_6.Items = {};
            app.DropDown7_6.ValueChangedFcn = createCallbackFcn(app, @DropDown7_6ValueChanged, true);
            app.DropDown7_6.Value = {};
            app.EditField7_2 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_2.ValueChangedFcn = createCallbackFcn(app, @EditField7_2ValueChanged, true);
            app.LinkShapeLabel = uilabel(app.GraphpropertiesPanel);
            app.LinkShapeLabel.Text = 'Link / Shape';
            app.RowColLabel = uilabel(app.GraphpropertiesPanel);
            app.RowColLabel.Text = 'Row / Col';
            app.DescriptorsLabel = uilabel(app.GraphpropertiesPanel);
            app.DescriptorsLabel.Text = 'Descriptors';
            app.MapFillLabel = uilabel(app.GraphpropertiesPanel);
            app.MapFillLabel.Text = 'Map / Fill';
            app.TypeFitLabel = uilabel(app.GraphpropertiesPanel);
            app.TypeFitLabel.Text = 'Type / Fit';
            app.EditField7_1 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_1.ValueChangedFcn = createCallbackFcn(app, @EditField7_1ValueChanged, true);
            app.LabelBins = uilabel(app.GraphpropertiesPanel);
            app.LabelBins.Text = 'Label / Bins';
            app.CheckBox7_3 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_3ValueChanged, true);
            app.CheckBox7_3.Text = 'norm ''y''';
            app.DropDown7_8 = uidropdown(app.GraphpropertiesPanel);
            app.DropDown7_8.Items = {};
            app.DropDown7_8.ValueChangedFcn = createCallbackFcn(app, @DropDown7_8ValueChanged2, true);
            app.DropDown7_8.Value = {};
            app.CheckBox7_2 = uicheckbox(app.GraphpropertiesPanel);
            app.CheckBox7_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox7_2ValueChanged, true);
            app.CheckBox7_2.Text = 'binned';
            app.EditField7_5 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_5.ValueChangedFcn = createCallbackFcn(app, @EditField7_5ValueChanged, true);
            app.EditField7_5.Enable = 'off';
            app.FitequationLabel = uilabel(app.GraphpropertiesPanel);
            app.FitequationLabel.Text = 'Fit equation';
            app.ListBox7_3 = uilistbox(app.GraphpropertiesPanel);
            app.ListBox7_3.Items = {};
            app.ListBox7_3.Multiselect = 'on';
            app.ListBox7_3.ValueChangedFcn = createCallbackFcn(app, @ListBox7_3ValueChanged, true);
            app.ListBox7_3.Tooltip = {'app.ListBox7_3.Value'};
            app.ListBox7_3.Value = {};
            app.FilesLabel = uilabel(app.GraphpropertiesPanel);
            app.FilesLabel.Text = 'File(s)';
            app.EditField7_4 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_4.ValueChangedFcn = createCallbackFcn(app, @EditField7_4ValueChanged, true);
            app.EditField7_3 = uieditfield(app.GraphpropertiesPanel, 'text');
            app.EditField7_3.ValueChangedFcn = createCallbackFcn(app, @EditField7_3ValueChanged, true);
            app.ListBox7_2 = uilistbox(app.ChoosegraphpropertiesPanel);
            app.ListBox7_2.Items = {};
            app.ListBox7_2.ValueChangedFcn = createCallbackFcn(app, @ListBox7_2ValueChanged, true);
            app.ListBox7_2.Value = {};
            app.ExtractPanel_4 = uipanel(app.PlotTab);
            app.ExtractPanel_4.Title = '2. Extract';
            app.ExtractButton7_1 = uibutton(app.ExtractPanel_4, 'push');
            app.ExtractButton7_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton7_1Pushed, true);
            app.ExtractButton7_1.Text = 'Extract';
            app.PlotpropertiesPanel = uipanel(app.PlotTab);
            app.PlotpropertiesPanel.Title = '3. Plot properties';
            app.WEditField7_1 = uieditfield(app.PlotpropertiesPanel, 'text');
            app.WEditField7_1.ValueChangedFcn = createCallbackFcn(app, @WEditField7_1ValueChanged, true);
            app.WEditField7_1.Value = '10';
            app.FontLabel = uilabel(app.PlotpropertiesPanel);
            app.FontLabel.Text = 'Font';
            app.FEditField7_1 = uieditfield(app.PlotpropertiesPanel, 'text');
            app.FEditField7_1.ValueChangedFcn = createCallbackFcn(app, @FEditField7_1ValueChanged, true);
            app.FEditField7_1.Value = '10';
            app.LWLabel = uilabel(app.PlotpropertiesPanel);
            app.LWLabel.Text = 'L / W';
            app.LEditField7_1 = uieditfield(app.PlotpropertiesPanel, 'text');
            app.LEditField7_1.ValueChangedFcn = createCallbackFcn(app, @LEditField7_1ValueChanged, true);
            app.LEditField7_1.Value = '10';
            app.MontageTab = uitab(app.TabGroup);
            app.MontageTab.Title = 'Montage';
            app.asclfilesPanel_3 = uipanel(app.MontageTab);
            app.asclfilesPanel_3.Title = '1. ascl file(s)';
            app.ListBox8_1 = uilistbox(app.asclfilesPanel_3);
            app.ListBox8_1.Items = {};
            app.ListBox8_1.Multiselect = 'on';
            app.ListBox8_1.Value = {};
            app.MontagePanel = uipanel(app.MontageTab);
            app.MontagePanel.Title = '5. Montage';
            app.MontageButton8_1 = uibutton(app.MontagePanel, 'push');
            app.MontageButton8_1.ButtonPushedFcn = createCallbackFcn(app, @MontageButton8_1Pushed, true);
            app.MontageButton8_1.Text = 'Montage';
            app.ExportPanel_3 = uipanel(app.MontageTab);
            app.ExportPanel_3.Title = '6. Export';
            app.ExportButton8_1 = uibutton(app.ExportPanel_3, 'push');
            app.ExportButton8_1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton8_1Pushed, true);
            app.ExportButton8_1.Text = 'Export';
            app.ChoosegalleryparametersPanel = uipanel(app.MontageTab);
            app.ChoosegalleryparametersPanel.Title = '4. Choose gallery parameters';
            app.ListBox8_2 = uilistbox(app.ChoosegalleryparametersPanel);
            app.ListBox8_2.Items = {};
            app.ListBox8_2.ValueChangedFcn = createCallbackFcn(app, @ListBox8_2ValueChanged, true);
            app.ListBox8_2.Value = {};
            app.AddButton8_1 = uibutton(app.ChoosegalleryparametersPanel, 'push');
            app.AddButton8_1.ButtonPushedFcn = createCallbackFcn(app, @AddButton8_1Pushed, true);
            app.AddButton8_1.Text = '+';
            app.EraseButton8_1 = uibutton(app.ChoosegalleryparametersPanel, 'push');
            app.EraseButton8_1.ButtonPushedFcn = createCallbackFcn(app, @EraseButton8_1Pushed, true);
            app.EraseButton8_1.Text = 'clear';
            app.GraphpropertiesPanel_2 = uipanel(app.ChoosegalleryparametersPanel);
            app.GraphpropertiesPanel_2.Title = 'Graph properties';
            app.DropDown8_2 = uidropdown(app.GraphpropertiesPanel_2);
            app.DropDown8_2.ValueChangedFcn = createCallbackFcn(app, @DropDown8_2ValueChanged, true);
            app.CheckBox8_1 = uicheckbox(app.GraphpropertiesPanel_2);
            app.CheckBox8_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox8_1ValueChanged, true);
            app.CheckBox8_1.Text = 'include';
            app.DropDown8_1 = uidropdown(app.GraphpropertiesPanel_2);
            app.DropDown8_1.ValueChangedFcn = createCallbackFcn(app, @DropDown8_1ValueChanged, true);
            app.FileShapeLabel_2 = uilabel(app.GraphpropertiesPanel_2);
            app.FileShapeLabel_2.Text = 'File / Shape';
            app.RowColLabel_2 = uilabel(app.GraphpropertiesPanel_2);
            app.RowColLabel_2.Text = 'Row / Col';
            app.EditField8_1 = uieditfield(app.GraphpropertiesPanel_2, 'text');
            app.EditField8_1.ValueChangedFcn = createCallbackFcn(app, @EditField8_1ValueChanged, true);
            app.LabelScale = uilabel(app.GraphpropertiesPanel_2);
            app.LabelScale.Text = 'Label / Scale';
            app.Spinner8_1 = uispinner(app.GraphpropertiesPanel_2);
            app.Spinner8_1.Limits = [1 Inf];
            app.Spinner8_1.ValueDisplayFormat = '%.0f';
            app.Spinner8_1.ValueChangedFcn = createCallbackFcn(app, @Spinner8_1ValueChanged, true);
            app.Spinner8_1.Value = 1;
            app.UIAxes8_1 = uiaxes(app.GraphpropertiesPanel_2);
            app.UIAxes8_1.XTick = [];
            app.UIAxes8_1.XTickLabel = {'[ ]'};
            app.UIAxes8_1.YTick = [];
            app.EditField8_2 = uieditfield(app.GraphpropertiesPanel_2, 'numeric');
            app.EditField8_2.ValueChangedFcn = createCallbackFcn(app, @EditField8_2ValueChanged, true);
            app.ParticleLabel = uilabel(app.GraphpropertiesPanel_2);
            app.ParticleLabel.Text = '# Particle';
            app.CheckBox8_2 = uicheckbox(app.GraphpropertiesPanel_2);
            app.CheckBox8_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox8_2ValueChanged, true);
            app.CheckBox8_2.Text = 'binned';
            app.EditField8_4 = uieditfield(app.GraphpropertiesPanel_2, 'text');
            app.EditField8_4.ValueChangedFcn = createCallbackFcn(app, @EditField8_4ValueChanged, true);
            app.EditField8_3 = uieditfield(app.GraphpropertiesPanel_2, 'text');
            app.EditField8_3.ValueChangedFcn = createCallbackFcn(app, @EditField8_3ValueChanged, true);
            app.ExtractPanel_6 = uipanel(app.MontageTab);
            app.ExtractPanel_6.Title = '2. Extract';
            app.ExtractButton8_1 = uibutton(app.ExtractPanel_6, 'push');
            app.ExtractButton8_1.ButtonPushedFcn = createCallbackFcn(app, @ExtractButton8_1Pushed, true);
            app.ExtractButton8_1.Text = 'Extract';
            app.GallerypropertiesPanel = uipanel(app.MontageTab);
            app.GallerypropertiesPanel.Title = '3. Gallery properties';
            app.TextLabel_2 = uilabel(app.GallerypropertiesPanel);
            app.TextLabel_2.Text = 'Text';
            app.FEditField8_1 = uieditfield(app.GallerypropertiesPanel, 'text');
            app.FEditField8_1.ValueChangedFcn = createCallbackFcn(app, @FEditField8_1ValueChanged, true);
            app.FEditField8_1.Value = '10';
            app.ImagesizeLabel = uilabel(app.GallerypropertiesPanel);
            app.ImagesizeLabel.Text = 'Image size';
            app.IEditField8_1 = uieditfield(app.GallerypropertiesPanel, 'text');
            app.IEditField8_1.ValueChangedFcn = createCallbackFcn(app, @IEditField8_1ValueChanged, true);
            app.IEditField8_1.Value = '10';
            app.MsgBox = uitextarea(app.ASAPUIFigure);
            % Show the figure after all components are created
            app.ASAPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASAP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ASAPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ASAPUIFigure)
        end
    end
end